classdef angleaxis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Image                         matlab.ui.control.Image
        MAE547ModellingcontrolofrobotsLabel  matlab.ui.control.Label
        AngleAxisRepresentationCalculatorLabel  matlab.ui.control.Label
        Te                            matlab.ui.control.Table
        CalculateButton               matlab.ui.control.Button
        RotationMatrixEditField       matlab.ui.control.NumericEditField
        RotationMatrixEditFieldLabel  matlab.ui.control.Label
        UITable                       matlab.ui.control.Table
        EnterButton                   matlab.ui.control.Button
    end

    
    properties (Access = private)
        Property % Storing requiured properties
        angax= [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EnterButton
        function EnterButtonPushed(app, event)
            size = [3, 3];
            varTypes = strings(1,3);
            varTypes = varTypes+'double';
            data = table('Size',size,'VariableTypes',varTypes);
            app.UITable.Data = data;
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            app.angax = eye(3);
            %n = app.RotationMatrixEditField.Value;
            DH = app.UITable.Data;
            dh= table2array(DH);
            angax = rotm2axang(dh);                % Converting Rotation matrix to angle-axis representation 
            angax(2,:) = -angax;                   % Storing 2nd set of angle axis representation in 2nd row 
            app.Te.Data = angax;                   % Diaplaying angle-axis output in display box
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EnterButton = uibutton(app.UIFigure, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Text = 'Enter';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.RotationMatrixEditFieldLabel = uilabel(app.UIFigure);
            app.RotationMatrixEditFieldLabel.Text = 'Rotation Matrix';
            app.RotationMatrixEditField = uieditfield(app.UIFigure, 'numeric');
            app.RotationMatrixEditField.Value = 3;
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.Te = uitable(app.UIFigure);
            app.Te.ColumnName = '';
            app.Te.RowName = {};
            app.AngleAxisRepresentationCalculatorLabel = uilabel(app.UIFigure);
            app.AngleAxisRepresentationCalculatorLabel.Text = 'Angle-Axis Representation Calculator';
            app.MAE547ModellingcontrolofrobotsLabel = uilabel(app.UIFigure);
            app.MAE547ModellingcontrolofrobotsLabel.Text = 'MAE 547: Modelling & control of robots';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'asulogo.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = angleaxis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end