classdef angmat < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Image                   matlab.ui.control.Image
        MAE547ModellingcontrolofrobotsLabel  matlab.ui.control.Label
        AngleaxistoRotationmatrixCalculatorLabel  matlab.ui.control.Label
        thetaradEditField       matlab.ui.control.NumericEditField
        thetaradEditFieldLabel  matlab.ui.control.Label
        ryEditField             matlab.ui.control.NumericEditField
        ryEditFieldLabel        matlab.ui.control.Label
        rxEditField             matlab.ui.control.NumericEditField
        rxEditFieldLabel        matlab.ui.control.Label
        rzEditField             matlab.ui.control.NumericEditField
        rzEditFieldLabel        matlab.ui.control.Label
        CalculateButton         matlab.ui.control.Button
        RotationmatrixLabel     matlab.ui.control.Label
        rotmtable               matlab.ui.control.Table
    end

    
    properties (Access = private)
        Property % Storing required properties
        theta = [];
        rx = [];
        ry = [];
        rz = [];
        rom = [];
        r = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            app.rom = eye(3);
            theta = app.thetaradEditField.Value;
            rx = app.rxEditField.Value;
            ry = app.ryEditField.Value;
            rz = app.rzEditField.Value;
            r = [theta rx ry rz];
            rom = axang2rotm(r)             % Converting angle-axis representation to rotation matrix
            app.rotmtable.Data = rom;       % Displaying Rotation matrix in output box
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.rotmtable = uitable(app.UIFigure);
            app.rotmtable.ColumnName = '';
            app.rotmtable.RowName = {};
            app.RotationmatrixLabel = uilabel(app.UIFigure);
            app.RotationmatrixLabel.Text = 'Rotation matrix';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.rzEditFieldLabel = uilabel(app.UIFigure);
            app.rzEditFieldLabel.Text = 'rz';
            app.rzEditField = uieditfield(app.UIFigure, 'numeric');
            app.rxEditFieldLabel = uilabel(app.UIFigure);
            app.rxEditFieldLabel.Text = 'rx';
            app.rxEditField = uieditfield(app.UIFigure, 'numeric');
            app.ryEditFieldLabel = uilabel(app.UIFigure);
            app.ryEditFieldLabel.Text = 'ry';
            app.ryEditField = uieditfield(app.UIFigure, 'numeric');
            app.thetaradEditFieldLabel = uilabel(app.UIFigure);
            app.thetaradEditFieldLabel.Text = 'theta (rad)';
            app.thetaradEditField = uieditfield(app.UIFigure, 'numeric');
            app.AngleaxistoRotationmatrixCalculatorLabel = uilabel(app.UIFigure);
            app.AngleaxistoRotationmatrixCalculatorLabel.Text = 'Angle-axis to Rotation matrix Calculator';
            app.MAE547ModellingcontrolofrobotsLabel = uilabel(app.UIFigure);
            app.MAE547ModellingcontrolofrobotsLabel.Text = 'MAE 547: Modelling & control of robots';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'asulogo.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = angmat

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end