classdef euler < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Image                    matlab.ui.control.Image
        MAE547ModellingcontrolofrobotsLabel  matlab.ui.control.Label
        EulerangletoRotationmatrixCalculatorLabel  matlab.ui.control.Label
        psiEditField             matlab.ui.control.NumericEditField
        psiEditFieldLabel        matlab.ui.control.Label
        phiEditField             matlab.ui.control.NumericEditField
        phiEditFieldLabel        matlab.ui.control.Label
        CalculateButton          matlab.ui.control.Button
        YourRotationmatrixLabel  matlab.ui.control.Label
        rotmtable                matlab.ui.control.Table
        thetaEditField           matlab.ui.control.NumericEditField
        thetaEditFieldLabel      matlab.ui.control.Label
        FrameDropDown            matlab.ui.control.DropDown
        FrameDropDownLabel       matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            %aassign values to drop down
            rotm = eye(3);
            theta = app.thetaEditField.Value;
            phi = app.phiEditField.Value;
            psi = app.psiEditField.Value;
            app.FrameDropDown.ItemsData=[1 2 ];       % Adding 2 options in dropdown tab
            axiz =app.FrameDropDown.Value;
            %test value
            if axiz == 1
                rx =[1 0 0;0 cosd(psi) -sind(psi);0 sind(psi) cosd(psi)];         % rotation matrix for x
                ry =[cosd(phi) 0 sind(phi);0 1 0;-sind(phi) 0 cosd(phi)];         % rotation matrix for y
                rz =[cosd(theta) -sind(theta) 0;sind(theta) cosd(theta) 0;0 0 1]; % rotation matrix for z
                rotm = rz*ry*rx;                      % Rotation ZYX with respect to current frame

            elseif axiz == 2
                
                ry =[cosd(phi) 0 sind(phi);0 1 0;-sind(phi) 0 cosd(phi)];% rotation matrix for y
                rz =[cosd(theta) -sind(theta) 0;sind(theta) cosd(theta) 0;0 0 1]; 
                rzz = [cosd(psi) -sind(psi) 0;sind(psi) cosd(psi) 0;0 0 1];% rotation matrix for z
                rotm = rz*ry*rzz;                     % Rotation ZYX with respect to current frame

            end
            app.rotmtable.Data = rotm;                % storing rotation output in display box
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FrameDropDownLabel = uilabel(app.UIFigure);
            app.FrameDropDownLabel.Text = 'Frame';
            app.FrameDropDown = uidropdown(app.UIFigure);
            app.FrameDropDown.Items = {'ZYX Current', 'ZYZ Fixed'};
            app.FrameDropDown.Value = 'ZYX Current';
            app.thetaEditFieldLabel = uilabel(app.UIFigure);
            app.thetaEditFieldLabel.Text = 'theta';
            app.thetaEditField = uieditfield(app.UIFigure, 'numeric');
            app.rotmtable = uitable(app.UIFigure);
            app.rotmtable.ColumnName = '';
            app.rotmtable.RowName = {};
            app.YourRotationmatrixLabel = uilabel(app.UIFigure);
            app.YourRotationmatrixLabel.Text = 'Your Rotation matrix';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.phiEditFieldLabel = uilabel(app.UIFigure);
            app.phiEditFieldLabel.Text = 'phi';
            app.phiEditField = uieditfield(app.UIFigure, 'numeric');
            app.psiEditFieldLabel = uilabel(app.UIFigure);
            app.psiEditFieldLabel.Text = 'psi';
            app.psiEditField = uieditfield(app.UIFigure, 'numeric');
            app.EulerangletoRotationmatrixCalculatorLabel = uilabel(app.UIFigure);
            app.EulerangletoRotationmatrixCalculatorLabel.Text = 'Euler angle to Rotation matrix Calculator';
            app.MAE547ModellingcontrolofrobotsLabel = uilabel(app.UIFigure);
            app.MAE547ModellingcontrolofrobotsLabel.Text = 'MAE 547: Modelling & control of robots';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'asulogo.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = euler

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end