classdef Jacobian < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        MAE547ModellingcontrolofrobotsLabel  matlab.ui.control.Label
        GeometricJacobianLabel       matlab.ui.control.Label
        Image                        matlab.ui.control.Image
        Te                           matlab.ui.control.Table
        ComputeJacobianButton        matlab.ui.control.Button
        UITable                      matlab.ui.control.Table
        NumberoflinksEditField       matlab.ui.control.NumericEditField
        NumberoflinksEditFieldLabel  matlab.ui.control.Label
        EnterButton                  matlab.ui.control.Button
    end

    
    properties (Access = private)
        % Storing required properties
        a_value = [];
        alpha_value = [];
        d_value  = [];
        theta_value = [];
        matrix_dh = [];
        A = [];
        
    end 
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EnterButton
        function EnterButtonPushed(app, event)
            size = [app.NumberoflinksEditField.Value, 5];
            varTypes = strings(1,5);
            varTypes = varTypes+'double';
            data = table('Size',size,'VariableTypes',varTypes);
            app.UITable.Data = data;
        end

        % Button pushed function: ComputeJacobianButton
        function ComputeJacobianButtonPushed(app, event)
            app.matrix_dh = eye(4); 
            n = app.NumberoflinksEditField.Value;
            DH = app.UITable.Data;
            dh= table2array(DH);
            z =eye(6,n+1);
            z(1:3, 1)= [0 0 1]';                   % Storing Value of Z0
            z(4:end,1)= [0 0 0]';                  % Storing Value of P0
             
            % Using of loop for multiple rotations
            for i=1:n
                app.A = [ cosd(dh(i,4)) -sind(dh(i,4))*cosd(dh(i,2)) sind(dh(i,4))*sind(dh(i,2)) dh(i,1)*cosd(dh(i,2)); sind(dh(i,4)) cosd(dh(i,4))*cosd(dh(i,2)) -cosd(dh(i,4))*sind(dh(i,2)) dh(i,1)*sind(dh(i,4)); 0 sind(dh(i,2)) cosd(dh(i,2)) dh(i,3); 0 0 0 1]; % Storing Transformation matrix
                app.matrix_dh = app.matrix_dh*app.A;
                z1 = app.matrix_dh(1:3,3);
                p1 = app.matrix_dh(1:3,4);
                z(1:3, i+1) = z1;
                z(4:end,i+1) = p1;
            end
            
            J = eye(6,n);
            for i = 1:n
               if dh(i,5) == 1
                    J(1:3,i) = z(1:3, i).*[z(4:end,n)-z(4:end,i)];
                    J(4:end,i)= z(1:3, i);
               
               else dh(i,5) == 2
                    J(1:3,i) = z(1:3, i);
                    J(4:end,i)= [0 0 0]';
               end
            end

            app.Te.Data = J;      % Displaying Geometric Jacobian in output box

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EnterButton = uibutton(app.UIFigure, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Text = 'Enter';
            app.NumberoflinksEditFieldLabel = uilabel(app.UIFigure);
            app.NumberoflinksEditFieldLabel.Text = 'Number of links';
            app.NumberoflinksEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberoflinksEditField.Value = 3;
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'a(i)'; 'alpha(i)'; 'd(i)'; 'theta(i)'; 'Joint (Rev =1 Pris = 2)'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.ComputeJacobianButton = uibutton(app.UIFigure, 'push');
            app.ComputeJacobianButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeJacobianButtonPushed, true);
            app.ComputeJacobianButton.Text = 'Compute Jacobian';
            app.Te = uitable(app.UIFigure);
            app.Te.ColumnName = '';
            app.Te.RowName = {};
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'asulogo.png');
            app.GeometricJacobianLabel = uilabel(app.UIFigure);
            app.GeometricJacobianLabel.Text = 'Geometric Jacobian';
            app.MAE547ModellingcontrolofrobotsLabel = uilabel(app.UIFigure);
            app.MAE547ModellingcontrolofrobotsLabel.Text = 'MAE 547: Modelling & control of robots';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Jacobian

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end