classdef rotmtoeuler < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        YoursetofEuleranglesLabel     matlab.ui.control.Label
        Image                         matlab.ui.control.Image
        MAE547ModellingcontrolofrobotsLabel  matlab.ui.control.Label
        RotationmatrixtoEulerangleCalculatorLabel  matlab.ui.control.Label
        Te                            matlab.ui.control.Table
        CalculateButton               matlab.ui.control.Button
        UITable                       matlab.ui.control.Table
        RotationMatrixEditField       matlab.ui.control.NumericEditField
        RotationMatrixEditFieldLabel  matlab.ui.control.Label
        EnterButton                   matlab.ui.control.Button
    end

    
    properties (Access = private)
        % Storing the required properties
        a_value = [];
        alpha_value = [];
        d_value  = [];
        theta_value = [];
        matrix_dh = [];
        A = [];
        
    end 
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EnterButton
        function EnterButtonPushed(app, event)
            size = [3, 3];
            varTypes = strings(1,3);
            varTypes = varTypes+'double';
            data = table('Size',size,'VariableTypes',varTypes);
            app.UITable.Data = data;
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            app.matrix_dh = eye(3); 
            n = app.RotationMatrixEditField.Value;
            DH = app.UITable.Data;
            dh= table2array(DH);                  % Converting table to homogeneous array 
            euler = rotm2eul(dh);                 % Converting Rotation matrix to euler angle
            p = [pi pi pi];
            euler(2,:) = p(1,:)-euler(1,:);       % Storing 2nd set of euler angle in 2nd row
            app.Te.Data = euler;                  % Displaying set of euler angles in Display box
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EnterButton = uibutton(app.UIFigure, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Text = 'Enter';
            app.RotationMatrixEditFieldLabel = uilabel(app.UIFigure);
            app.RotationMatrixEditFieldLabel.Text = 'Rotation Matrix';
            app.RotationMatrixEditField = uieditfield(app.UIFigure, 'numeric');
            app.RotationMatrixEditField.Value = 3;
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.Te = uitable(app.UIFigure);
            app.Te.ColumnName = '';
            app.Te.RowName = {};
            app.RotationmatrixtoEulerangleCalculatorLabel = uilabel(app.UIFigure);
            app.RotationmatrixtoEulerangleCalculatorLabel.Text = 'Rotation matrix to Euler angle Calculator';
            app.MAE547ModellingcontrolofrobotsLabel = uilabel(app.UIFigure);
            app.MAE547ModellingcontrolofrobotsLabel.Text = 'MAE 547: Modelling & control of robots';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'asulogo.png');
            app.YoursetofEuleranglesLabel = uilabel(app.UIFigure);
            app.YoursetofEuleranglesLabel.Text = 'Your set of Euler angles';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = rotmtoeuler

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end