classdef Direct_kinematics < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Image                        matlab.ui.control.Image
        MAE547ModellingcontrolofrobotsLabel  matlab.ui.control.Label
        TransformationMatrixCalculatorLabel  matlab.ui.control.Label
        Te                           matlab.ui.control.Table
        ComputeFinalTransformationButton  matlab.ui.control.Button
        UITable                      matlab.ui.control.Table
        NumberoflinksEditField       matlab.ui.control.NumericEditField
        NumberoflinksEditFieldLabel  matlab.ui.control.Label
        EnterButton                  matlab.ui.control.Button
    end

    
    properties (Access = private)
        % Storing required properties
        a_value = [];
        alpha_value = [];
        d_value  = [];
        theta_value = [];
        matrix_dh = [];
        A = [];
        
    end 
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EnterButton
        function EnterButtonPushed(app, event)
            size = [app.NumberoflinksEditField.Value, 4];
            varTypes = strings(1,4);
            varTypes = varTypes+'double';
            data = table('Size',size,'VariableTypes',varTypes);
            app.UITable.Data = data;
        end

        % Button pushed function: ComputeFinalTransformationButton
        function ComputeFinalTransformationButtonPushed(app, event)
            app.matrix_dh = eye(4); 
            n = app.NumberoflinksEditField.Value;
            DH = app.UITable.Data;
            dh= table2array(DH);                 % Converting table to homogeneous array
             % Using of loop for multiple rotations
            for i=1:n
                app.A = [ cosd(dh(i,4)) -sind(dh(i,4))*cosd(dh(i,2)) sind(dh(i,4))*sind(dh(i,2)) dh(i,1)*cosd(dh(i,2)); sind(dh(i,4)) cosd(dh(i,4))*cosd(dh(i,2)) -cosd(dh(i,4))*sind(dh(i,2)) dh(i,1)*sind(dh(i,4)); 0 sind(dh(i,2)) cosd(dh(i,2)) dh(i,3); 0 0 0 1]; % Transformation matrix
                app.matrix_dh = app.matrix_dh*app.A;
            end
            app.Te.Data = app.matrix_dh;         % Displaying Transformation matrix in output matrix
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EnterButton = uibutton(app.UIFigure, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Text = 'Enter';
            app.NumberoflinksEditFieldLabel = uilabel(app.UIFigure);
            app.NumberoflinksEditFieldLabel.Text = 'Number of links';
            app.NumberoflinksEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberoflinksEditField.Value = 3;
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'a(i)'; 'alpha(i)'; 'd(i)'; 'theta(i)'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.ComputeFinalTransformationButton = uibutton(app.UIFigure, 'push');
            app.ComputeFinalTransformationButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeFinalTransformationButtonPushed, true);
            app.ComputeFinalTransformationButton.Text = 'Compute Final Transformation';
            app.Te = uitable(app.UIFigure);
            app.Te.ColumnName = '';
            app.Te.RowName = {};
            app.TransformationMatrixCalculatorLabel = uilabel(app.UIFigure);
            app.TransformationMatrixCalculatorLabel.Text = 'Transformation Matrix Calculator';
            app.MAE547ModellingcontrolofrobotsLabel = uilabel(app.UIFigure);
            app.MAE547ModellingcontrolofrobotsLabel.Text = 'MAE 547: Modelling & control of robots';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'asulogo.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Direct_kinematics

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end