classdef multiplerotations < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Fix1Current2Label              matlab.ui.control.Label
        X1Y2Z3Label                    matlab.ui.control.Label
        MAE547ModellingcontrolofrobotsLabel  matlab.ui.control.Label
        MultiRotationsCalculatorLabel  matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        Te                             matlab.ui.control.Table
        ComputeFinalTransformationButton  matlab.ui.control.Button
        UITable                        matlab.ui.control.Table
        NumberofrotationsEditField     matlab.ui.control.NumericEditField
        NumberofrotationsEditFieldLabel  matlab.ui.control.Label
        EnterButton                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        % Storing required properties
        a_value = [];
        alpha_value = [];
        d_value  = [];
        theta_value = [];
        matrix_dh = [];
        A = [];
        
    end 
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EnterButton
        function EnterButtonPushed(app, event)
            size = [app.NumberofrotationsEditField.Value, 3];
            varTypes = strings(1,3);
            varTypes = varTypes+'double';
            data = table('Size',size,'VariableTypes',varTypes);
            app.UITable.Data = data;
        end

        % Button pushed function: ComputeFinalTransformationButton
        function ComputeFinalTransformationButtonPushed(app, event)
            rotm = eye(3); 
            n = app.NumberofrotationsEditField.Value;
            DH = app.UITable.Data;
            dh= table2array(DH);     
           
% forming a loop for multiple rotations
            for i=1:n
                if dh(i,1) == 1                % rotation matrix with respect to x axis 
                    r=[1 0 0;0 cosd(dh(i,2)) -sind(dh(i,2));0 sind(dh(i,2)) cosd(dh(i,2))]; 
                    if dh(i,3) == 1
                        rotm = rotm*r;
                    else 
                        rotm = r*rotm;
                    end
    
                elseif dh(i,1) == 2             % rotation matrix with respect to y axis 
                    r=[cosd(dh(i,2)) 0 sind(dh(i,2));0 1 0;-sind(dh(i,2)) 0 cosd(dh(i,2))]; 
                    if dh(i,3) == 1
                        rotm = rotm*r;
                    else 
                        rotm = r*rotm;
                    end
                 
                elseif dh(i,1) == 3             % rotation matrix with respect to z axis 
                    r=[cosd(dh(i,2)) -sind(dh(i,2)) 0;sind(dh(i,2)) cosd(dh(i,2)) 0;0 0 1]; 
                    if dh(i,3) == 1
                        rotm = rotm*r;
                    else 
                        rotm = r*rotm;
                    end
                end
                
            end
            app.Te.Data = rotm';                 % Displaying required rotation matrix in output box
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EnterButton = uibutton(app.UIFigure, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Text = 'Enter';
            app.NumberofrotationsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofrotationsEditFieldLabel.Text = 'Number of rotations';
            app.NumberofrotationsEditField = uieditfield(app.UIFigure, 'numeric');
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Axis'; 'Angle'; 'Fixed/Current'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.ComputeFinalTransformationButton = uibutton(app.UIFigure, 'push');
            app.ComputeFinalTransformationButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeFinalTransformationButtonPushed, true);
            app.ComputeFinalTransformationButton.Text = 'Compute Final Transformation';
            app.Te = uitable(app.UIFigure);
            app.Te.ColumnName = '';
            app.Te.RowName = {};
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'asulogo.png');
            app.MultiRotationsCalculatorLabel = uilabel(app.UIFigure);
            app.MultiRotationsCalculatorLabel.Text = 'Multi-Rotations Calculator';
            app.MAE547ModellingcontrolofrobotsLabel = uilabel(app.UIFigure);
            app.MAE547ModellingcontrolofrobotsLabel.Text = 'MAE 547: Modelling & control of robots';
            app.X1Y2Z3Label = uilabel(app.UIFigure);
            app.X1Y2Z3Label.Text = 'X=1 Y=2 Z=3';
            app.Fix1Current2Label = uilabel(app.UIFigure);
            app.Fix1Current2Label.Text = 'Fix =1 / Current=2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = multiplerotations

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end