classdef quattomat < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        Image                matlab.ui.control.Image
        MAE547ModellingcontrolofrobotsLabel  matlab.ui.control.Label
        QuaterniontoRotationMatrixCalculatorLabel  matlab.ui.control.Label
        etaEditField         matlab.ui.control.NumericEditField
        etaEditFieldLabel    matlab.ui.control.Label
        eyEditField          matlab.ui.control.NumericEditField
        eyEditFieldLabel     matlab.ui.control.Label
        exEditField          matlab.ui.control.NumericEditField
        exEditFieldLabel     matlab.ui.control.Label
        ezEditField          matlab.ui.control.NumericEditField
        ezEditFieldLabel     matlab.ui.control.Label
        CalculateButton      matlab.ui.control.Button
        RotationmatrixLabel  matlab.ui.control.Label
        rotmtable            matlab.ui.control.Table
    end

    
    properties (Access = private)
        Property % Storing required properties
        eta = [];
        ex = [];
        ey = [];
        ez = [];
        rom = [];
        r = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            app.rom = eye(3);
            eta = app.etaEditField.Value;
            ex = app.exEditField.Value;
            ey = app.eyEditField.Value;
            ez = app.ezEditField.Value;
            r = [eta ex ey ez];            % Storing values of eta, ex, ry, ez in a vector
            rom = quat2rotm(r);            % Converting Quaternion to Rotation matrix
            app.rotmtable.Data = rom;      % Displaying rotation matrix in output box
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.rotmtable = uitable(app.UIFigure);
            app.rotmtable.ColumnName = '';
            app.rotmtable.RowName = {};
            app.RotationmatrixLabel = uilabel(app.UIFigure);
            app.RotationmatrixLabel.Text = 'Rotation matrix';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.ezEditFieldLabel = uilabel(app.UIFigure);
            app.ezEditFieldLabel.Text = 'ez';
            app.ezEditField = uieditfield(app.UIFigure, 'numeric');
            app.exEditFieldLabel = uilabel(app.UIFigure);
            app.exEditFieldLabel.Text = 'ex';
            app.exEditField = uieditfield(app.UIFigure, 'numeric');
            app.eyEditFieldLabel = uilabel(app.UIFigure);
            app.eyEditFieldLabel.Text = 'ey';
            app.eyEditField = uieditfield(app.UIFigure, 'numeric');
            app.etaEditFieldLabel = uilabel(app.UIFigure);
            app.etaEditFieldLabel.Text = 'eta';
            app.etaEditField = uieditfield(app.UIFigure, 'numeric');
            app.QuaterniontoRotationMatrixCalculatorLabel = uilabel(app.UIFigure);
            app.QuaterniontoRotationMatrixCalculatorLabel.Text = 'Quaternion to Rotation Matrix Calculator';
            app.MAE547ModellingcontrolofrobotsLabel = uilabel(app.UIFigure);
            app.MAE547ModellingcontrolofrobotsLabel.Text = 'MAE 547: Modelling & control of robots';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'asulogo.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = quattomat

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end