classdef rotationmatrix < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Image                          matlab.ui.control.Image
        MAE547ModellingcontrolofrobotsLabel  matlab.ui.control.Label
        RotationMatrixCalculatorLabel  matlab.ui.control.Label
        CalculateButton                matlab.ui.control.Button
        RotationmatrixLabel            matlab.ui.control.Label
        rotmtable                      matlab.ui.control.Table
        AngleEditField                 matlab.ui.control.NumericEditField
        AngleEditFieldLabel            matlab.ui.control.Label
        DropDown                       matlab.ui.control.DropDown
        DropDownLabel                  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            %aassign values to drop down
            rotm = eye(3);
            an = app.AngleEditField.Value;
            app.DropDown.ItemsData=[1 2 3];     % Adding 3 axis options in dropdown tab
            axiz =app.DropDown.Value;
            
            %test value
            if axiz == 1                        % Rotation with respect to x axis
                r=[1 0 0;0 cosd(an) -sind(an);0 sind(an) cosd(an)]; % rotation matrix for x
                rotm = rotm*r;                  

            elseif axiz == 2                    % Rotation with respect to y axis
                r=[cosd(an) 0 sind(an);0 1 0;-sind(an) 0 cosd(an)]; % rotation matrix for y
                rotm = rotm*r;
             
            elseif axiz == 3                    % Rotation with respect to z axis
                r=[cosd(an) -sind(an) 0;sind(an) cosd(an) 0;0 0 1]; % rotation matrix for z
                rotm = rotm*r;
            end
            app.rotmtable.Data = rotm;          % Displaying rotation with respective axis in output box
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DropDownLabel = uilabel(app.UIFigure);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'X', 'Y', 'Z'};
            app.DropDown.Value = 'X';
            app.AngleEditFieldLabel = uilabel(app.UIFigure);
            app.AngleEditFieldLabel.Text = 'Angle';
            app.AngleEditField = uieditfield(app.UIFigure, 'numeric');
            app.rotmtable = uitable(app.UIFigure);
            app.rotmtable.ColumnName = '';
            app.rotmtable.RowName = {};
            app.RotationmatrixLabel = uilabel(app.UIFigure);
            app.RotationmatrixLabel.Text = 'Rotation matrix';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.RotationMatrixCalculatorLabel = uilabel(app.UIFigure);
            app.RotationMatrixCalculatorLabel.Text = 'Rotation Matrix Calculator';
            app.MAE547ModellingcontrolofrobotsLabel = uilabel(app.UIFigure);
            app.MAE547ModellingcontrolofrobotsLabel.Text = 'MAE 547: Modelling & control of robots';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'asulogo.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = rotationmatrix

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end